classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        Tab                      matlab.ui.container.Tab
        DHParamaterLabel         matlab.ui.control.Label
        DH11                     matlab.ui.control.NumericEditField
        DH12                     matlab.ui.control.NumericEditField
        DH13                     matlab.ui.control.NumericEditField
        DH14                     matlab.ui.control.NumericEditField
        DH21                     matlab.ui.control.NumericEditField
        DH22                     matlab.ui.control.NumericEditField
        DH23                     matlab.ui.control.NumericEditField
        DH24                     matlab.ui.control.NumericEditField
        DH31                     matlab.ui.control.NumericEditField
        DH32                     matlab.ui.control.NumericEditField
        DH33                     matlab.ui.control.NumericEditField
        DH34                     matlab.ui.control.NumericEditField
        DH41                     matlab.ui.control.NumericEditField
        DH42                     matlab.ui.control.NumericEditField
        DH43                     matlab.ui.control.NumericEditField
        DH44                     matlab.ui.control.NumericEditField
        DH51                     matlab.ui.control.NumericEditField
        DH52                     matlab.ui.control.NumericEditField
        DH53                     matlab.ui.control.NumericEditField
        DH54                     matlab.ui.control.NumericEditField
        DH61                     matlab.ui.control.NumericEditField
        DH62                     matlab.ui.control.NumericEditField
        DH63                     matlab.ui.control.NumericEditField
        DH64                     matlab.ui.control.NumericEditField
        Label                    matlab.ui.control.Label
        Label_2                  matlab.ui.control.Label
        Label_3                  matlab.ui.control.Label
        Label_4                  matlab.ui.control.Label
        Label_5                  matlab.ui.control.Label
        Label_6                  matlab.ui.control.Label
        theta_iLabel             matlab.ui.control.Label
        d_iLabel                 matlab.ui.control.Label
        a_iLabel                 matlab.ui.control.Label
        alpha_iLabel             matlab.ui.control.Label
        ConfigurationLabel       matlab.ui.control.Label
        config11                 matlab.ui.control.NumericEditField
        config21                 matlab.ui.control.NumericEditField
        config31                 matlab.ui.control.NumericEditField
        config41                 matlab.ui.control.NumericEditField
        config51                 matlab.ui.control.NumericEditField
        config61                 matlab.ui.control.NumericEditField
        config12                 matlab.ui.control.NumericEditField
        config22                 matlab.ui.control.NumericEditField
        config32                 matlab.ui.control.NumericEditField
        config42                 matlab.ui.control.NumericEditField
        config52                 matlab.ui.control.NumericEditField
        config62                 matlab.ui.control.NumericEditField
        config13                 matlab.ui.control.NumericEditField
        config23                 matlab.ui.control.NumericEditField
        config33                 matlab.ui.control.NumericEditField
        config43                 matlab.ui.control.NumericEditField
        config53                 matlab.ui.control.NumericEditField
        config63                 matlab.ui.control.NumericEditField
        config14                 matlab.ui.control.NumericEditField
        config24                 matlab.ui.control.NumericEditField
        config34                 matlab.ui.control.NumericEditField
        config44                 matlab.ui.control.NumericEditField
        config54                 matlab.ui.control.NumericEditField
        config64                 matlab.ui.control.NumericEditField
        q1Label                  matlab.ui.control.Label
        q2Label                  matlab.ui.control.Label
        q3Label                  matlab.ui.control.Label
        q4Label                  matlab.ui.control.Label
        q5Label                  matlab.ui.control.Label
        q6Label                  matlab.ui.control.Label
        Config1Label             matlab.ui.control.Label
        Config2Label             matlab.ui.control.Label
        Config3Label             matlab.ui.control.Label
        Config4Label             matlab.ui.control.Label
        btnConfig1               matlab.ui.control.Button
        btnConfig2               matlab.ui.control.Button
        btnConfig3               matlab.ui.control.Button
        btnConfig4               matlab.ui.control.Button
        limit11                  matlab.ui.control.NumericEditField
        limit12                  matlab.ui.control.NumericEditField
        limit21                  matlab.ui.control.NumericEditField
        limit22                  matlab.ui.control.NumericEditField
        limit31                  matlab.ui.control.NumericEditField
        limit32                  matlab.ui.control.NumericEditField
        limit41                  matlab.ui.control.NumericEditField
        limit42                  matlab.ui.control.NumericEditField
        limit51                  matlab.ui.control.NumericEditField
        limit52                  matlab.ui.control.NumericEditField
        limit61                  matlab.ui.control.NumericEditField
        limit62                  matlab.ui.control.NumericEditField
        JointlimitLabel          matlab.ui.control.Label
        lowerLabel               matlab.ui.control.Label
        upperLabel               matlab.ui.control.Label
        btnApplyLimit            matlab.ui.control.Button
        btnCal                   matlab.ui.control.Button
        resultField              matlab.ui.control.EditField
        xField                   matlab.ui.control.NumericEditField
        yField                   matlab.ui.control.NumericEditField
        zField                   matlab.ui.control.NumericEditField
        rollField                matlab.ui.control.NumericEditField
        pitchField               matlab.ui.control.NumericEditField
        yawField                 matlab.ui.control.NumericEditField
        XLabel                   matlab.ui.control.Label
        YLabel                   matlab.ui.control.Label
        ZLabel                   matlab.ui.control.Label
        rollLabel                matlab.ui.control.Label
        pitchLabel               matlab.ui.control.Label
        yawLabel                 matlab.ui.control.Label
        DesirePositionmRadLabel  matlab.ui.control.Label
        loadx                    matlab.ui.control.NumericEditField
        loady                    matlab.ui.control.NumericEditField
        loadz                    matlab.ui.control.NumericEditField
        loadMx                   matlab.ui.control.NumericEditField
        loadMy                   matlab.ui.control.NumericEditField
        loadMz                   matlab.ui.control.NumericEditField
        XLabel_2                 matlab.ui.control.Label
        YLabel_2                 matlab.ui.control.Label
        ZLabel_2                 matlab.ui.control.Label
        M_xLabel                 matlab.ui.control.Label
        M_yLabel                 matlab.ui.control.Label
        M_zLabel                 matlab.ui.control.Label
        PayLoadNNmLabel          matlab.ui.control.Label
        T_1                      matlab.ui.control.NumericEditField
        T_2                      matlab.ui.control.NumericEditField
        T_3                      matlab.ui.control.NumericEditField
        T_4                      matlab.ui.control.NumericEditField
        T_5                      matlab.ui.control.NumericEditField
        T_6                      matlab.ui.control.NumericEditField
        ActuatedTorqueNmLabel    matlab.ui.control.Label
        Joint1Label              matlab.ui.control.Label
        Joint2Label              matlab.ui.control.Label
        Joint3Label              matlab.ui.control.Label
        Joint4Label              matlab.ui.control.Label
        Joint5Label              matlab.ui.control.Label
        Joint6Label              matlab.ui.control.Label
        ChangpowBotLabel         matlab.ui.control.Label
        TPiPBossGangstersLabel   matlab.ui.control.Label
        HomeConfigurationButton  matlab.ui.control.Button
        Tab2                     matlab.ui.container.Tab
    end

    
    properties (Access = private)
        Config = [];
        DH_table = []; % Description
        Plot = []; % Description
        Torque = []; % Description
        Fn = [];
    end
    
    methods (Access = private)


        function show_torque(app,T)
            for i = 1:size(app.Torque,1)
                app.Torque(i).Visible = 'on'; 
                app.Torque(i).Value = T(i);
            end
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Config = [ app.config11 , app.config12 , app.config13 , app.config14 ;
                           app.config21 , app.config22 , app.config23 , app.config24 ;
                           app.config31 , app.config32 , app.config33 , app.config34 ;
                           app.config41 , app.config42 , app.config43 , app.config44 ;
                           app.config51 , app.config52 , app.config53 , app.config54 ;
                           app.config61 , app.config62 , app.config63 , app.config64 ];
            
            app.DH_table = [ app.DH11.Value , app.DH12.Value , app.DH13.Value , app.DH14.Value ;
                             app.DH21.Value , app.DH22.Value , app.DH23.Value , app.DH24.Value ;
                             app.DH31.Value , app.DH32.Value , app.DH33.Value , app.DH34.Value ;
                             app.DH41.Value , app.DH42.Value , app.DH43.Value , app.DH44.Value ;
                             app.DH51.Value , app.DH52.Value , app.DH53.Value , app.DH54.Value ;
                             app.DH61.Value , app.DH62.Value , app.DH63.Value , app.DH64.Value ];
            
            app.Plot = [app.btnConfig1 , app.btnConfig2 , app.btnConfig3 , app.btnConfig4 ];    
            
            app.Torque = [app.T_1 ; app.T_2 ; app.T_3 ; app.T_4 ; app.T_5 ; app.T_6 ];
            
            app.Fn = [app.loadx.Value ;
                      app.loady.Value ;
                      app.loadz.Value ;
                      app.loadMx.Value ;
                      app.loadMy.Value ;
                      app.loadMz.Value ];
            
            for i = 1:size(app.Config,1)
                for j = 1:size(app.Config,2)
                    app.Config(i,j).Visible = 'off';
                    app.Plot(1,j).Enable = 'off';
                    app.Torque(i).Visible = 'off';
                end
            end
            app.btnApplyLimit.Enable = 'off';
        end

        % Button pushed function: HomeConfigurationButton
        function HomeConfigurationButtonPushed(app, event)
            [~, ~, R_e, p_e] = forwardKinematics([0 0 0 0 0 0]',app.DH_table,[1 1 1 1 1 1]'); 
            RPY = cvtRot2RPY(R_e);
            app.xField.Value = p_e(1);
            app.yField.Value = p_e(2);
            app.zField.Value = p_e(3);
            
            app.rollField.Value = RPY(1);
            app.pitchField.Value = RPY(2);
            app.yawField.Value = RPY(3);
            
            Show([0 0 0 0 0 0]',app.DH_table)
            
        end

        % Button pushed function: btnApplyLimit
        function btnApplyLimitButtonPushed(app, event)
            limit = [ app.limit11.Value , app.limit12.Value ;
                app.limit21.Value , app.limit22.Value ;
                app.limit31.Value , app.limit32.Value ;
                app.limit41.Value , app.limit42.Value ;
                app.limit51.Value , app.limit52.Value ;
                app.limit61.Value , app.limit62.Value ];
            
            q = [];
            for i = 1:size(app.Config,1)
                for j = 1:size(app.Config,2)
                    q(i,j) = app.Config(i,j).Value;
                end
            end
            
            [~,status] = jointLimit(q,limit);
            for i = 1:size(app.Config,1)
                for j = 1:size(app.Config,2)
                    if ~(status(1,j) == 1)
                        app.Config(i,j).Enable = 'off';
                        app.Plot(1,j).Enable = 'off';
                    end
                end
            end


        end

        % Button pushed function: btnCal
        function btnCalButtonPushed(app, event)
            
            p_e = [ app.xField.Value ; app.yField.Value ; app.zField.Value ];
            rpy = [ app.rollField.Value ; app.pitchField.Value ; app.yawField.Value ];
            
            
            limit = [ app.limit11.Value , app.limit12.Value ;
                app.limit21.Value , app.limit22.Value ;
                app.limit31.Value , app.limit32.Value ;
                app.limit41.Value , app.limit42.Value ;
                app.limit51.Value , app.limit52.Value ;
                app.limit61.Value , app.limit62.Value ];
            
            [q, status] = inverseKinematics(app.DH_table,p_e,rpy,limit);
            
            
            app.Fn = [app.loadx.Value  ;
                      app.loady.Value  ;
                      app.loadz.Value  ;
                      app.loadMx.Value ;
                      app.loadMy.Value ;
                      app.loadMz.Value ];
            
            if status == 1
                app.resultField.Value = 'Solution Found!!';
                n = size(q,2);
                index = 4;
                for i = 1:4
                    if size(app.Config,2) == n
                        break
                    end    
                    app.Config(:,index) = [];
                    index = index - 1;
                end
                
                for i = 1:size(q,1)
                    for j = 1:size(q,2)
                        app.Config(i,j).Value = q(i,j);
                        app.Config(i,j).Visible = 'on';
                        app.Config(i,j).Enable = 'on';
                        app.Plot(1,j).Enable = 'on';
                        app.Torque(i).Visible = 'off';
                    end
                end
                app.btnApplyLimit.Enable = 'on';
                
            elseif status == 0
                for i = 1:size(app.Config,1)
                    for j = 1:size(app.Config,2)
                        app.Config(i,j).Visible = 'off';
                    end
                end
                app.resultField.Value = 'Out of workspace';
                app.btnApplyLimit.Enable = 'off';
                
            else
                for i = 1:size(app.Config,1)
                    for j = 1:size(app.Config,2)
                        app.Config(i,j).Visible = 'off';
                    end
                end
                app.resultField.Value = 'out of jointlimit';
                app.btnApplyLimit.Enable = 'off';
            end
        end

        % Button pushed function: btnConfig1
        function btnConfig1ButtonPushed(app, event)
            for i = 1:6
                q(i,1) = app.Config(i,1).Value;
            end
            Show(q,app.DH_table)
            T = torqueCal(q,app.Fn);
            show_torque(app,T);
            
        end

        % Button pushed function: btnConfig2
        function btnConfig2ButtonPushed(app, event)
            for i = 1:6
                q(i,1) = app.Config(i,2).Value;
            end
            Show(q,app.DH_table)
            T = torqueCal(q,app.Fn);
            show_torque(app,T);
            
        end

        % Button pushed function: btnConfig3
        function btnConfig3ButtonPushed(app, event)
            for i = 1:6
                q(i,1) = app.Config(i,3).Value;
            end
            Show(q,app.DH_table)
            T = torqueCal(q,app.Fn);
            show_torque(app,T);
            
        end

        % Button pushed function: btnConfig4
        function btnConfig4ButtonPushed(app, event)
            for i = 1:6
                q(i,1) = app.Config(i,4).Value;
            end
            Show(q,app.DH_table)
            T = torqueCal(q,app.Fn);
            show_torque(app,T);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.DHParamaterLabel = uilabel(app.Tab);
            app.DHParamaterLabel.Text = 'DH Paramater';
            app.DH11 = uieditfield(app.Tab, 'numeric');
            app.DH11.ValueDisplayFormat = '%11.5g';
            app.DH11.Value = -1.5708;
            app.DH12 = uieditfield(app.Tab, 'numeric');
            app.DH12.ValueDisplayFormat = '%11.5g';
            app.DH12.Value = 250.5;
            app.DH13 = uieditfield(app.Tab, 'numeric');
            app.DH13.ValueDisplayFormat = '%11.5g';
            app.DH14 = uieditfield(app.Tab, 'numeric');
            app.DH14.ValueDisplayFormat = '%11.5g';
            app.DH14.Value = 1.5708;
            app.DH21 = uieditfield(app.Tab, 'numeric');
            app.DH21.ValueDisplayFormat = '%11.5g';
            app.DH21.Value = 1.5708;
            app.DH22 = uieditfield(app.Tab, 'numeric');
            app.DH22.ValueDisplayFormat = '%11.5g';
            app.DH23 = uieditfield(app.Tab, 'numeric');
            app.DH23.ValueDisplayFormat = '%11.5g';
            app.DH23.Value = 500;
            app.DH24 = uieditfield(app.Tab, 'numeric');
            app.DH24.ValueDisplayFormat = '%11.5g';
            app.DH31 = uieditfield(app.Tab, 'numeric');
            app.DH31.ValueDisplayFormat = '%11.5g';
            app.DH31.Value = -1.0472;
            app.DH32 = uieditfield(app.Tab, 'numeric');
            app.DH32.ValueDisplayFormat = '%11.5g';
            app.DH33 = uieditfield(app.Tab, 'numeric');
            app.DH33.ValueDisplayFormat = '%11.5g';
            app.DH33.Value = 100;
            app.DH34 = uieditfield(app.Tab, 'numeric');
            app.DH34.ValueDisplayFormat = '%11.5g';
            app.DH34.Value = -1.5708;
            app.DH41 = uieditfield(app.Tab, 'numeric');
            app.DH41.ValueDisplayFormat = '%11.5g';
            app.DH42 = uieditfield(app.Tab, 'numeric');
            app.DH42.ValueDisplayFormat = '%11.5g';
            app.DH42.Value = -507.2;
            app.DH43 = uieditfield(app.Tab, 'numeric');
            app.DH43.ValueDisplayFormat = '%11.5g';
            app.DH44 = uieditfield(app.Tab, 'numeric');
            app.DH44.ValueDisplayFormat = '%11.5g';
            app.DH44.Value = 1.5708;
            app.DH51 = uieditfield(app.Tab, 'numeric');
            app.DH51.ValueDisplayFormat = '%11.5g';
            app.DH51.Value = -0.5236;
            app.DH52 = uieditfield(app.Tab, 'numeric');
            app.DH52.ValueDisplayFormat = '%11.5g';
            app.DH53 = uieditfield(app.Tab, 'numeric');
            app.DH53.ValueDisplayFormat = '%11.5g';
            app.DH54 = uieditfield(app.Tab, 'numeric');
            app.DH54.ValueDisplayFormat = '%11.5g';
            app.DH54.Value = -1.5708;
            app.DH61 = uieditfield(app.Tab, 'numeric');
            app.DH61.ValueDisplayFormat = '%11.5g';
            app.DH62 = uieditfield(app.Tab, 'numeric');
            app.DH62.ValueDisplayFormat = '%11.5g';
            app.DH62.Value = -230;
            app.DH63 = uieditfield(app.Tab, 'numeric');
            app.DH63.ValueDisplayFormat = '%11.5g';
            app.DH64 = uieditfield(app.Tab, 'numeric');
            app.DH64.ValueDisplayFormat = '%11.5g';
            app.DH64.Value = 3.14;
            app.Label = uilabel(app.Tab);
            app.Label.Text = '0->1';
            app.Label_2 = uilabel(app.Tab);
            app.Label_2.Text = '1->2';
            app.Label_3 = uilabel(app.Tab);
            app.Label_3.Text = '2->3';
            app.Label_4 = uilabel(app.Tab);
            app.Label_4.Text = '3->4';
            app.Label_5 = uilabel(app.Tab);
            app.Label_5.Text = '4->5';
            app.Label_6 = uilabel(app.Tab);
            app.Label_6.Text = '5->6';
            app.theta_iLabel = uilabel(app.Tab);
            app.theta_iLabel.Text = 'theta_i';
            app.d_iLabel = uilabel(app.Tab);
            app.d_iLabel.Text = 'd_i';
            app.a_iLabel = uilabel(app.Tab);
            app.a_iLabel.Text = 'a_i';
            app.alpha_iLabel = uilabel(app.Tab);
            app.alpha_iLabel.Text = 'alpha_i';
            app.ConfigurationLabel = uilabel(app.Tab);
            app.ConfigurationLabel.Text = 'Configuration';
            app.config11 = uieditfield(app.Tab, 'numeric');
            app.config11.ValueDisplayFormat = '%11.5g';
            app.config11.Value = 1.5708;
            app.config21 = uieditfield(app.Tab, 'numeric');
            app.config21.ValueDisplayFormat = '%11.5g';
            app.config21.Value = 1.5708;
            app.config31 = uieditfield(app.Tab, 'numeric');
            app.config31.ValueDisplayFormat = '%11.5g';
            app.config31.Value = 1.5708;
            app.config41 = uieditfield(app.Tab, 'numeric');
            app.config41.ValueDisplayFormat = '%11.5g';
            app.config41.Value = 1.5708;
            app.config51 = uieditfield(app.Tab, 'numeric');
            app.config51.ValueDisplayFormat = '%11.5g';
            app.config51.Value = 1.5708;
            app.config61 = uieditfield(app.Tab, 'numeric');
            app.config61.ValueDisplayFormat = '%11.5g';
            app.config61.Value = 1.5708;
            app.config12 = uieditfield(app.Tab, 'numeric');
            app.config12.ValueDisplayFormat = '%11.5g';
            app.config12.Value = 1.5708;
            app.config22 = uieditfield(app.Tab, 'numeric');
            app.config22.ValueDisplayFormat = '%11.5g';
            app.config22.Value = 1.5708;
            app.config32 = uieditfield(app.Tab, 'numeric');
            app.config32.ValueDisplayFormat = '%11.5g';
            app.config32.Value = 1.5708;
            app.config42 = uieditfield(app.Tab, 'numeric');
            app.config42.ValueDisplayFormat = '%11.5g';
            app.config42.Value = 1.5708;
            app.config52 = uieditfield(app.Tab, 'numeric');
            app.config52.ValueDisplayFormat = '%11.5g';
            app.config52.Value = 1.5708;
            app.config62 = uieditfield(app.Tab, 'numeric');
            app.config62.ValueDisplayFormat = '%11.5g';
            app.config62.Value = 1.5708;
            app.config13 = uieditfield(app.Tab, 'numeric');
            app.config13.ValueDisplayFormat = '%11.5g';
            app.config13.Value = 1.5708;
            app.config23 = uieditfield(app.Tab, 'numeric');
            app.config23.ValueDisplayFormat = '%11.5g';
            app.config23.Value = 1.5708;
            app.config33 = uieditfield(app.Tab, 'numeric');
            app.config33.ValueDisplayFormat = '%11.5g';
            app.config33.Value = 1.5708;
            app.config43 = uieditfield(app.Tab, 'numeric');
            app.config43.ValueDisplayFormat = '%11.5g';
            app.config43.Value = 1.5708;
            app.config53 = uieditfield(app.Tab, 'numeric');
            app.config53.ValueDisplayFormat = '%11.5g';
            app.config53.Value = 1.5708;
            app.config63 = uieditfield(app.Tab, 'numeric');
            app.config63.ValueDisplayFormat = '%11.5g';
            app.config63.Value = 1.5708;
            app.config14 = uieditfield(app.Tab, 'numeric');
            app.config14.ValueDisplayFormat = '%11.5g';
            app.config14.Value = 1.5708;
            app.config24 = uieditfield(app.Tab, 'numeric');
            app.config24.ValueDisplayFormat = '%11.5g';
            app.config24.Value = 1.5708;
            app.config34 = uieditfield(app.Tab, 'numeric');
            app.config34.ValueDisplayFormat = '%11.5g';
            app.config34.Value = 1.5708;
            app.config44 = uieditfield(app.Tab, 'numeric');
            app.config44.ValueDisplayFormat = '%11.5g';
            app.config44.Value = 1.5708;
            app.config54 = uieditfield(app.Tab, 'numeric');
            app.config54.ValueDisplayFormat = '%11.5g';
            app.config54.Value = 1.5708;
            app.config64 = uieditfield(app.Tab, 'numeric');
            app.config64.ValueDisplayFormat = '%11.5g';
            app.config64.Value = 1.5708;
            app.q1Label = uilabel(app.Tab);
            app.q1Label.Text = 'q1';
            app.q2Label = uilabel(app.Tab);
            app.q2Label.Text = 'q2';
            app.q3Label = uilabel(app.Tab);
            app.q3Label.Text = 'q3';
            app.q4Label = uilabel(app.Tab);
            app.q4Label.Text = 'q4';
            app.q5Label = uilabel(app.Tab);
            app.q5Label.Text = 'q5';
            app.q6Label = uilabel(app.Tab);
            app.q6Label.Text = 'q6';
            app.Config1Label = uilabel(app.Tab);
            app.Config1Label.Text = 'Config. 1';
            app.Config2Label = uilabel(app.Tab);
            app.Config2Label.Text = 'Config. 2';
            app.Config3Label = uilabel(app.Tab);
            app.Config3Label.Text = 'Config. 3';
            app.Config4Label = uilabel(app.Tab);
            app.Config4Label.Text = 'Config. 4';
            app.btnConfig1 = uibutton(app.Tab, 'push');
            app.btnConfig1.ButtonPushedFcn = createCallbackFcn(app, @btnConfig1ButtonPushed, true);
            app.btnConfig1.Text = 'Plot';
            app.btnConfig2 = uibutton(app.Tab, 'push');
            app.btnConfig2.ButtonPushedFcn = createCallbackFcn(app, @btnConfig2ButtonPushed, true);
            app.btnConfig2.Text = 'Plot';
            app.btnConfig3 = uibutton(app.Tab, 'push');
            app.btnConfig3.ButtonPushedFcn = createCallbackFcn(app, @btnConfig3ButtonPushed, true);
            app.btnConfig3.Text = 'Plot';
            app.btnConfig4 = uibutton(app.Tab, 'push');
            app.btnConfig4.ButtonPushedFcn = createCallbackFcn(app, @btnConfig4ButtonPushed, true);
            app.btnConfig4.Text = 'Plot';
            app.limit11 = uieditfield(app.Tab, 'numeric');
            app.limit11.Value = -0.7854;
            app.limit12 = uieditfield(app.Tab, 'numeric');
            app.limit12.Value = 3.927;
            app.limit21 = uieditfield(app.Tab, 'numeric');
            app.limit21.Value = -1.0472;
            app.limit22 = uieditfield(app.Tab, 'numeric');
            app.limit22.Value = 0.7854;
            app.limit31 = uieditfield(app.Tab, 'numeric');
            app.limit32 = uieditfield(app.Tab, 'numeric');
            app.limit32.Value = 1.8326;
            app.limit41 = uieditfield(app.Tab, 'numeric');
            app.limit41.Value = -3.1427;
            app.limit42 = uieditfield(app.Tab, 'numeric');
            app.limit42.Value = 3.143;
            app.limit51 = uieditfield(app.Tab, 'numeric');
            app.limit51.Value = -1.2217;
            app.limit52 = uieditfield(app.Tab, 'numeric');
            app.limit52.Value = 2.2689;
            app.limit61 = uieditfield(app.Tab, 'numeric');
            app.limit61.Value = -3.1427;
            app.limit62 = uieditfield(app.Tab, 'numeric');
            app.limit62.Value = 3.143;
            app.JointlimitLabel = uilabel(app.Tab);
            app.JointlimitLabel.Text = 'Joint limit';
            app.lowerLabel = uilabel(app.Tab);
            app.lowerLabel.Text = 'lower';
            app.upperLabel = uilabel(app.Tab);
            app.upperLabel.Text = 'upper';
            app.btnApplyLimit = uibutton(app.Tab, 'push');
            app.btnApplyLimit.ButtonPushedFcn = createCallbackFcn(app, @btnApplyLimitButtonPushed, true);
            app.btnApplyLimit.Text = 'Apply Joint limit';
            app.btnCal = uibutton(app.Tab, 'push');
            app.btnCal.ButtonPushedFcn = createCallbackFcn(app, @btnCalButtonPushed, true);
            app.btnCal.Text = 'Calculate';
            app.resultField = uieditfield(app.Tab, 'text');
            app.xField = uieditfield(app.Tab, 'numeric');
            app.yField = uieditfield(app.Tab, 'numeric');
            app.zField = uieditfield(app.Tab, 'numeric');
            app.rollField = uieditfield(app.Tab, 'numeric');
            app.pitchField = uieditfield(app.Tab, 'numeric');
            app.yawField = uieditfield(app.Tab, 'numeric');
            app.XLabel = uilabel(app.Tab);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.Tab);
            app.YLabel.Text = 'Y';
            app.ZLabel = uilabel(app.Tab);
            app.ZLabel.Text = 'Z';
            app.rollLabel = uilabel(app.Tab);
            app.rollLabel.Text = 'roll';
            app.pitchLabel = uilabel(app.Tab);
            app.pitchLabel.Text = 'pitch';
            app.yawLabel = uilabel(app.Tab);
            app.yawLabel.Text = 'yaw';
            app.DesirePositionmRadLabel = uilabel(app.Tab);
            app.DesirePositionmRadLabel.Text = 'Desire Position(m,Rad)';
            app.loadx = uieditfield(app.Tab, 'numeric');
            app.loady = uieditfield(app.Tab, 'numeric');
            app.loadz = uieditfield(app.Tab, 'numeric');
            app.loadz.Value = -10;
            app.loadMx = uieditfield(app.Tab, 'numeric');
            app.loadMy = uieditfield(app.Tab, 'numeric');
            app.loadMz = uieditfield(app.Tab, 'numeric');
            app.XLabel_2 = uilabel(app.Tab);
            app.XLabel_2.Text = 'X';
            app.YLabel_2 = uilabel(app.Tab);
            app.YLabel_2.Text = 'Y';
            app.ZLabel_2 = uilabel(app.Tab);
            app.ZLabel_2.Text = 'Z';
            app.M_xLabel = uilabel(app.Tab);
            app.M_xLabel.Text = 'M_x';
            app.M_yLabel = uilabel(app.Tab);
            app.M_yLabel.Text = 'M_y';
            app.M_zLabel = uilabel(app.Tab);
            app.M_zLabel.Text = 'M_z';
            app.PayLoadNNmLabel = uilabel(app.Tab);
            app.PayLoadNNmLabel.Text = 'Pay Load(N,N.m)';
            app.T_1 = uieditfield(app.Tab, 'numeric');
            app.T_2 = uieditfield(app.Tab, 'numeric');
            app.T_3 = uieditfield(app.Tab, 'numeric');
            app.T_4 = uieditfield(app.Tab, 'numeric');
            app.T_5 = uieditfield(app.Tab, 'numeric');
            app.T_6 = uieditfield(app.Tab, 'numeric');
            app.ActuatedTorqueNmLabel = uilabel(app.Tab);
            app.ActuatedTorqueNmLabel.Text = 'Actuated Torque(N.m)';
            app.Joint1Label = uilabel(app.Tab);
            app.Joint1Label.Text = 'Joint1';
            app.Joint2Label = uilabel(app.Tab);
            app.Joint2Label.Text = 'Joint2';
            app.Joint3Label = uilabel(app.Tab);
            app.Joint3Label.Text = 'Joint3';
            app.Joint4Label = uilabel(app.Tab);
            app.Joint4Label.Text = 'Joint4';
            app.Joint5Label = uilabel(app.Tab);
            app.Joint5Label.Text = 'Joint5';
            app.Joint6Label = uilabel(app.Tab);
            app.Joint6Label.Text = 'Joint6';
            app.ChangpowBotLabel = uilabel(app.Tab);
            app.ChangpowBotLabel.Text = '''Changpow Bot..''';
            app.TPiPBossGangstersLabel = uilabel(app.Tab);
            app.TPiPBossGangstersLabel.Text = 'T''Pi & P''Boss & Gangsters';
            app.HomeConfigurationButton = uibutton(app.Tab, 'push');
            app.HomeConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @HomeConfigurationButtonPushed, true);
            app.HomeConfigurationButton.Text = 'Home Configuration';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end